classdef PrintView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        PathEditFieldLabel         matlab.ui.control.Label
        PathEditField              matlab.ui.control.EditField
        LoadDataButton             matlab.ui.control.Button
        BrowseButton               matlab.ui.control.Button
        PRINTOUTDataViewLabel      matlab.ui.control.Label
        CTSliderLabel              matlab.ui.control.Label
        CTSlider                   matlab.ui.control.Slider
        CBCTSliderLabel            matlab.ui.control.Label
        CBCTSlider                 matlab.ui.control.Slider
        UIAxes                     matlab.ui.control.UIAxes
        UIAxes_2                   matlab.ui.control.UIAxes
        CBCTScanChoiceButtonGroup  matlab.ui.container.ButtonGroup
        PreButton                  matlab.ui.control.RadioButton
        PostButton                 matlab.ui.control.RadioButton
        CBCTScanLoadStatusLabel    matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        UIAxes_3                   matlab.ui.control.UIAxes
        CTrSliderLabel             matlab.ui.control.Label
        CTrSlider                  matlab.ui.control.Slider
        UIAxes_4                   matlab.ui.control.UIAxes
        UIAxes_5                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        matfilename % Description
        ContourName % Description
        PlanCTMaskcell % Description
        PlanCTPath % Description
        PlanCTInfo % Description
        PlanCTData % Description
        PlanCTLoca % Description
        PosCBCTInfo % Description
        PosCBCTPath % Description
        PosCBCTData % Description
        PosCBCTLoca % Description
        PreCBCTInfo % Description
        PreCBCTPath % Description
        PreCBCTData % Description
        PreCBCTLoca % Description
        CTLen % Description
        PreCBCTLen % Description
        PosCBCTLen % Description
        CurCBCTInfo % Description
        CurCBCTPath % Description
        CurCBCTData % Description
        CurCBCTLoca
        CurCBCTLen
        PlanCTrData
        PlanCTrDataR
        CTrsize
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [file,mypath] = uigetfile('/home/arun/Documents/MATLAB/ImageDB/PrintoutDB');
            filepath=[char(mypath),char(file)];
            app.PathEditField.Value=filepath;
            app.matfilename=file;
            addpath(mypath);
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            msgbox('Loading in progress');
%             filepath=app.PathEditField.Value;
%             load(app.matfilename,'ContourName','PlanCTMaskcell','PlanCTPath','PlanCTInfo','PlanCTData','PlanCTLoca','PosCBCTInfo','PosCBCTPath','PosCBCTData','PosCBCTLoca');
        load(app.matfilename);
        app.ContourName=ContourName;
        app.PlanCTMaskcell=PlanCTMaskcell; % Description
        app.PlanCTPath=PlanCTPath; % Description
        app.PlanCTInfo=PlanCTInfo; % Description
        app.PlanCTData=PlanCTData; % Description
        app.PlanCTLoca=PlanCTLoca; % Description
        app.PosCBCTInfo=PosCBCTInfo; % Description
        app.PosCBCTPath=PosCBCTPath; % Description
        app.PosCBCTData=PosCBCTData; % Description
        app.PosCBCTLoca=PosCBCTLoca; % Description
        app.PreCBCTInfo=PreCBCTInfo; % Description
        app.PreCBCTPath=PreCBCTPath; % Description
        app.PreCBCTData=PreCBCTData; % Description
        app.PreCBCTLoca=PreCBCTLoca; % Description
        app.CTLen=length(PlanCTLoca);
        app.PreCBCTLen=length(PreCBCTLoca);
        app.PosCBCTLen=length(PosCBCTLoca);

            
        app.CTSlider.Limits = [1 app.CTLen];
        imshow(app.PlanCTData(:,:,1),[],'parent',app.UIAxes);
        app.UIAxes.Title.String=['Z= ',num2str(app.PlanCTLoca(1)),' Ind= ',num2str(1)];
            
            app.CurCBCTInfo=app.PreCBCTInfo; % Description
            app.CurCBCTPath=app.PreCBCTPath; % Description
            app.CurCBCTData=app.PreCBCTData; % Description
            app.CurCBCTLoca=app.PreCBCTLoca;
            app.CurCBCTLen=app.PreCBCTLen;
            app.CBCTSlider.Limits = [1 app.CurCBCTLen];
        
        app.CBCTSlider.Limits = [1 app.CurCBCTLen];
        imshow(app.CurCBCTData(:,:,1),[],'parent',app.UIAxes_2);
        app.UIAxes_2.Title.String=['Z= ',num2str(app.CurCBCTLoca(1)),' Ind= ',num2str(1)];
        selectedButton = app.CBCTScanChoiceButtonGroup.SelectedObject;
        app.Label.Text=selectedButton.Text;
        
        %Resizing Planning CT according to CBCT size
       app.PlanCTrDataR=imresize3(app.PlanCTData,[double(app.CurCBCTInfo{1,1}.Rows) double(app.CurCBCTInfo{1,1}.Columns) app.CurCBCTLen]);

%         app.UIAxes_3.Title.String=['Z= ',num2str(app.CurCBCTLoca(1)),' Ind= ',num2str(1)];       
        

fixedHeader=app.PreCBCTInfo{1,1};
movingHeader=app.PlanCTInfo{1,1};
fixedVolume=app.PreCBCTData;
movingVolume=app.PlanCTData;
[optimizer,metric] = imregconfig('multimodal');
Rfixed  = imref3d(size(fixedVolume),fixedHeader.PixelSpacing(2),fixedHeader.PixelSpacing(1),fixedHeader.SliceThickness);
Rmoving = imref3d(size(movingVolume),movingHeader.PixelSpacing(2),movingHeader.PixelSpacing(1),movingHeader.SliceThickness);
optimizer.InitialRadius = 0.004;
optimizer.MaximumIterations = 200;
[movingRegisteredVolume, ~] = imregister(movingVolume,Rmoving, fixedVolume,Rfixed, 'affine', optimizer, metric);
       app.CTrsize=size(movingRegisteredVolume);
       
        app.PlanCTrData=movingRegisteredVolume;
        app.CTrSlider.Limits = [1 app.CTrsize(3)];
        imshow(app.PlanCTrData(:,:,1),[],'parent',app.UIAxes_3);
        app.UIAxes_3.Title.String=['Ind= ',num2str(1)]; 
        
        f1=imshow(app.PlanCTrData(:,:,1),[],'parent',app.UIAxes_4);
        hold(app.UIAxes_4,"on");
        f2=imshow(app.CurCBCTData(:,:,1),[],'parent',app.UIAxes_4);
        hold(app.UIAxes_4,"off")
        app.UIAxes_4.Title.String=['Ind= ',num2str(1)]; 
        set(f2,'AlphaData', 0.5);

        imshow(app.PlanCTrDataR(:,:,1),[],'parent',app.UIAxes_5);
        app.UIAxes_5.Title.String=['Resize Ind= ',num2str(1)];
                    msgbox('Mat file loaded & processed');
        
        end

        % Callback function
        function CBCTScanchoiceDropDownValueChanged(app, event)


        end

        % Value changed function: CTSlider
        function CTSliderValueChanged(app, event)
            ctvalue = round(app.CTSlider.Value);
             imshow(app.PlanCTData(:,:,ctvalue),[],'Parent',app.UIAxes);
             app.UIAxes.Title.String=['Z= ',num2str(app.PlanCTLoca(ctvalue)),' Ind= ',num2str(ctvalue)];


        end

        % Value changed function: CBCTSlider
        function CBCTSliderValueChanged(app, event)
            cbctvalue = round(app.CBCTSlider.Value);
             imshow(app.CurCBCTData(:,:,cbctvalue),[],'Parent',app.UIAxes_2);
             app.UIAxes_2.Title.String=['CBCT Z= ',num2str(app.CurCBCTLoca(cbctvalue)),' Ind= ',num2str(cbctvalue)];
        f1=imshow(app.PlanCTrData(:,:,cbctvalue),[],'parent',app.UIAxes_4);
        hold(app.UIAxes_4,"on");
        f2=imshow(app.CurCBCTData(:,:,cbctvalue),[],'parent',app.UIAxes_4);
        hold(app.UIAxes_4,"off")
        app.UIAxes_4.Title.String=['Ind= ',num2str(cbctvalue)]; 
        set(f2,'AlphaData', 0.5);
            
        end

        % Selection changed function: CBCTScanChoiceButtonGroup
        function CBCTScanChoiceButtonGroupSelectionChanged(app, event)
            selectedButton = app.CBCTScanChoiceButtonGroup.SelectedObject;
            if strcmpi(selectedButton.Text,'Pre')
            app.CurCBCTInfo=app.PreCBCTInfo; % Description
            app.CurCBCTPath=app.PreCBCTPath; % Description
            app.CurCBCTData=app.PreCBCTData; % Description
            app.CurCBCTLoca=app.PreCBCTLoca;
            app.CurCBCTLen=app.PreCBCTLen;
            app.CBCTSlider.Limits = [1 app.CurCBCTLen];
            app.Label.Text=selectedButton.Text;
            elseif strcmpi(selectedButton.Text,'Post')
            app.CurCBCTInfo=app.PosCBCTInfo; % Description
            app.CurCBCTPath=app.PosCBCTPath; % Description
            app.CurCBCTData=app.PosCBCTData; % Description
            app.CurCBCTLoca=app.PosCBCTLoca;
            app.CurCBCTLen=app.PosCBCTLen;
            app.CBCTSlider.Limits = [1 app.CurCBCTLen];
            app.Label.Text=selectedButton.Text;
            end
        end

        % Value changed function: CTrSlider
        function CTrSliderValueChanged(app, event)
            ctvalue1 = round(app.CTrSlider.Value);
            imshow(app.PlanCTrData(:,:,ctvalue1),[],'Parent',app.UIAxes_3);
%             app.UIAxes.Title.String=['Z= ',num2str(app.PlanCTLoca(ctvalue1)),' Ind= ',num2str(ctvalue1)];
            app.UIAxes_3.Title.String=['Rigid Ind= ',num2str(ctvalue1)];
            imshow(app.PlanCTrDataR(:,:,ctvalue1),[],'parent',app.UIAxes_5);
            app.UIAxes_5.Title.String=['Resize Ind= ',num2str(ctvalue1)];
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PathEditFieldLabel = uilabel(app.UIFigure);
            app.PathEditFieldLabel.Text = 'Path';
            app.PathEditField = uieditfield(app.UIFigure, 'text');
            app.PathEditField.Value = '/home/arun/Documents/MATLAB/ImageDB/PrintoutDB';
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.PRINTOUTDataViewLabel = uilabel(app.UIFigure);
            app.PRINTOUTDataViewLabel.Text = 'PRINTOUT Data View';
            app.CTSliderLabel = uilabel(app.UIFigure);
            app.CTSliderLabel.Text = 'CT Slider';
            app.CTSlider = uislider(app.UIFigure);
            app.CTSlider.ValueChangedFcn = createCallbackFcn(app, @CTSliderValueChanged, true);
            app.CBCTSliderLabel = uilabel(app.UIFigure);
            app.CBCTSliderLabel.Text = 'CBCT Slider';
            app.CBCTSlider = uislider(app.UIFigure);
            app.CBCTSlider.ValueChangedFcn = createCallbackFcn(app, @CBCTSliderValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.CBCTScanChoiceButtonGroup = uibuttongroup(app.UIFigure);
            app.CBCTScanChoiceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CBCTScanChoiceButtonGroupSelectionChanged, true);
            app.CBCTScanChoiceButtonGroup.Title = 'CBCT Scan Choice';
            app.PreButton = uiradiobutton(app.CBCTScanChoiceButtonGroup);
            app.PreButton.Text = 'Pre';
            app.PreButton.Value = true;
            app.PostButton = uiradiobutton(app.CBCTScanChoiceButtonGroup);
            app.PostButton.Text = 'Post';
            app.CBCTScanLoadStatusLabel = uilabel(app.UIFigure);
            app.CBCTScanLoadStatusLabel.Text = 'CBCT Scan Load Status';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, '')
            ylabel(app.UIAxes_3, '')
            app.CTrSliderLabel = uilabel(app.UIFigure);
            app.CTrSliderLabel.Text = 'CTr Slider';
            app.CTrSlider = uislider(app.UIFigure);
            app.CTrSlider.ValueChangedFcn = createCallbackFcn(app, @CTrSliderValueChanged, true);
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, '')
            ylabel(app.UIAxes_4, '')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'Title')
            xlabel(app.UIAxes_5, '')
            ylabel(app.UIAxes_5, '')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PrintView

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end